classdef appTrandformacionSenal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TransformarButton             matlab.ui.control.Button
        reproducir_audio_transformadoButton  matlab.ui.control.Button
        Ver_audio_transformadoButton  matlab.ui.control.Button
        EditField_2                   matlab.ui.control.NumericEditField
        EscalamientotemporalLabel     matlab.ui.control.Label
        CheckBox                      matlab.ui.control.CheckBox
        InvercionTemporalLabel        matlab.ui.control.Label
        Des_temporal_num              matlab.ui.control.NumericEditField
        Des_temporal_signo            matlab.ui.control.DropDown
        DesplazamientotemporalLabel   matlab.ui.control.Label
        TransformacionesLabel         matlab.ui.control.Label
        reproducir_audioButton        matlab.ui.control.Button
        Cargar_wavButton              matlab.ui.control.Button
        AudioOriginalLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        senal_audio % Description
        frecuencia
        tamano_audio
        segundos_tiempo_audio

        
        %---transformaciones
        nueva_senal_audio
        nuevo_segundos_tiempo_audio

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Cargar_wavButton
        function cargar_audio(app, event)
             %--cargar audio
             [nombre_archivo, ubicacion_archivo] = uigetfile('*.wav');
             ubicacion_exacta_archivo = fullfile(ubicacion_archivo, nombre_archivo);

             [app.senal_audio, app.frecuencia] = audioread(ubicacion_exacta_archivo);

             app.tamano_audio = length(app.senal_audio);
             vector_tiempo_audio = 0:app.tamano_audio-1;
             app.segundos_tiempo_audio = vector_tiempo_audio/app.frecuencia;

             %--ver audio
             plot(app.UIAxes,app.segundos_tiempo_audio, app.senal_audio);
             title(app.UIAxes, 'Señal original');
        end

        % Button pushed function: reproducir_audioButton
        function reproducir_audio_original(app, event)
            sound(app.senal_audio,app.frecuencia);
        end

        % Button pushed function: TransformarButton
        function transformaciones(app, event)

            app.nueva_senal_audio = zeros(app.tamano_audio,1);

            %----desplazamiento
            signo=app.Des_temporal_signo.Value;
            num_desp=app.Des_temporal_num.Value;

            if num_desp ~= 0
                %--suma
                if strcmp(signo, '+')
                    for posicion = 1: app.tamano_audio
                        desplazamiento = posicion+num_desp;
                        if desplazamiento >= 1 && desplazamiento <= app.tamano_audio
                            app.nueva_senal_audio(posicion) = app.senal_audio(desplazamiento);
                        else 
                            app.nueva_senal_audio(posicion) = 0;
                        end
                    end
                 %--resta
                else
                    for posicion = 1: app.tamano_audio
                        desplazamiento = posicion-num_desp;
                        if desplazamiento >= 1 && desplazamiento <= app.tamano_audio
                            app.nueva_senal_audio(posicion) = app.senal_audio(desplazamiento);
                        else 
                            app.nueva_senal_audio(posicion) = 0;
                        end
                    end

                end
            end
            %---------------

            app.nuevo_segundos_tiempo_audio = 0:(length(app.nueva_senal_audio)-1)/app.frecuencia;

        end

        % Button pushed function: Ver_audio_transformadoButton
        function ver_audio_transformado(app, event)
            plot(app.UIAxes,app.nuevo_segundos_tiempo_audio, app.nueva_senal_audio);
            title(app.UIAxes, 'Señal Transformada');
        end

        % Button pushed function: reproducir_audio_transformadoButton
        function reproducir_audio_transformado(app, event)
            sound(app.nueva_senal_audio,app.nuevo_segundos_tiempo_audio);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ver Senal')
            xlabel(app.UIAxes, 'n (segundos)')
            ylabel(app.UIAxes, 'x[n] (amplitud)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [-10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'-10'; '-9'; '-8'; '-7'; '-6'; '-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [-1 0 1];
            app.UIAxes.YTickLabel = {'-1'; '0'; '1'};
            app.AudioOriginalLabel = uilabel(app.UIFigure);
            app.AudioOriginalLabel.Text = 'Audio Original:';
            app.Cargar_wavButton = uibutton(app.UIFigure, 'push');
            app.Cargar_wavButton.ButtonPushedFcn = createCallbackFcn(app, @cargar_audio, true);
            app.Cargar_wavButton.Text = 'Cargar .wav';
            app.reproducir_audioButton = uibutton(app.UIFigure, 'push');
            app.reproducir_audioButton.ButtonPushedFcn = createCallbackFcn(app, @reproducir_audio_original, true);
            app.reproducir_audioButton.Text = 'reproducir audio';
            app.TransformacionesLabel = uilabel(app.UIFigure);
            app.TransformacionesLabel.Text = 'Transformaciones:';
            app.DesplazamientotemporalLabel = uilabel(app.UIFigure);
            app.DesplazamientotemporalLabel.Text = 'Desplazamiento temporal -->';
            app.Des_temporal_signo = uidropdown(app.UIFigure);
            app.Des_temporal_signo.Items = {'+', '-'};
            app.Des_temporal_signo.Value = '+';
            app.Des_temporal_num = uieditfield(app.UIFigure, 'numeric');
            app.InvercionTemporalLabel = uilabel(app.UIFigure);
            app.InvercionTemporalLabel.Text = 'Invercion Temporal -->';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.EscalamientotemporalLabel = uilabel(app.UIFigure);
            app.EscalamientotemporalLabel.Text = 'Escalamiento temporal -->';
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Ver_audio_transformadoButton = uibutton(app.UIFigure, 'push');
            app.Ver_audio_transformadoButton.ButtonPushedFcn = createCallbackFcn(app, @ver_audio_transformado, true);
            app.Ver_audio_transformadoButton.Text = 'Ver audio transformado';
            app.reproducir_audio_transformadoButton = uibutton(app.UIFigure, 'push');
            app.reproducir_audio_transformadoButton.ButtonPushedFcn = createCallbackFcn(app, @reproducir_audio_transformado, true);
            app.reproducir_audio_transformadoButton.Text = 'reproducir audio transformado';
            app.TransformarButton = uibutton(app.UIFigure, 'push');
            app.TransformarButton.ButtonPushedFcn = createCallbackFcn(app, @transformaciones, true);
            app.TransformarButton.Text = 'Transformar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appTrandformacionSenal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end